classdef Formulas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        equationTab                  matlab.ui.container.Tab
        epsilonEditField             matlab.ui.control.EditField
        PhngtrnhLabel_5              matlab.ui.control.Label
        errorTextArea                matlab.ui.control.TextArea
        ErrorTextAreaLabel           matlab.ui.control.Label
        equationButton               matlab.ui.control.Button
        repeatEditField              matlab.ui.control.NumericEditField
        RepeatLabel                  matlab.ui.control.Label
        rootEditField                matlab.ui.control.NumericEditField
        RootLabel                    matlab.ui.control.Label
        bEquation_EditField          matlab.ui.control.NumericEditField
        aEquation_EditField          matlab.ui.control.NumericEditField
        intervalLabel                matlab.ui.control.Label
        equation_methodListBox       matlab.ui.control.ListBox
        MethodLabel                  matlab.ui.control.Label
        intervalEditField            matlab.ui.control.EditField
        funcEditField                matlab.ui.control.EditField
        PhngtrnhLabel                matlab.ui.control.Label
        equationUIAxes               matlab.ui.control.UIAxes
        interpolationTab             matlab.ui.container.Tab
        polynomialArea               matlab.ui.control.TextArea
        PhngtrnhLabel_4              matlab.ui.control.Label
        interpolation_rootField      matlab.ui.control.NumericEditField
        interpolation_x0EditField    matlab.ui.control.NumericEditField
        interpolationLabel           matlab.ui.control.Label
        interpolation_methodListBox  matlab.ui.control.ListBox
        MethodLabel_2                matlab.ui.control.Label
        interpolation_YField         matlab.ui.control.EditField
        PhngtrnhLabel_3              matlab.ui.control.Label
        interpolation_XField         matlab.ui.control.EditField
        PhngtrnhLabel_2              matlab.ui.control.Label
        interpolationButton          matlab.ui.control.Button
        interpolationUIAxes          matlab.ui.control.UIAxes
        regressionTab                matlab.ui.container.Tab
        regressionUIAxes             matlab.ui.control.UIAxes
        derivationTab                matlab.ui.container.Tab
        derivationUIAxes             matlab.ui.control.UIAxes
        integrationTab               matlab.ui.container.Tab
        integrationUIAxes            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: equationButton
        function equationButtonPushed(app, event)
            method = app.equation_methodListBox.Value;
            fx = app.funcEditField.Value;
            a = app.aEquation_EditField.Value;
            b = app.bEquation_EditField.Value;
            e = str2double(app.epsilonEditField.Value);
            fprintf('%s\n', method);
            if strcmp(method, 'Bisection')
                [x0, n, er] = bisection(fx, a, b, e);
            elseif strcmp(method, 'Interation')
                [x0, n] = interation(fx, a, b, e);
            elseif strcmp(method, 'Newton')
                [x0, n, er] = newtonEquation(fx, a, b, e);
            end
            % Print error
            app.errorTextArea.Value = er;
            % Plot the graph
            fx = str2func(['@(x)', fx]);
            t = a:(b-a)/100:b;
            y = arrayfun(fx, t);
            plot(app.equationUIAxes, t, y);
            app.rootEditField.Value = x0;
            app.repeatEditField.Value = n;
        end

        % Button pushed function: interpolationButton
        function interpolationButtonPushed(app, event)
            method = app.interpolation_methodListBox.Value;
            x = str2double(strsplit(app.interpolation_XField.Value, ', ')); 
            y = str2double(strsplit(app.interpolation_YField.Value, ', ')); 
            x0 = app.interpolation_x0EditField.Value;
            
            if strcmp(method, 'Lagrange')
                [P, result] = lagrange(x, y, x0);
            elseif strcmp(method, 'Newton')
                [P, result] = newtonInterpolation(x, y, x0);
            end
            % Print result and polynomial
            P_str = string(P);
            app.interpolation_rootField.Value = result;
            app.polynomialArea.Value = P_str;
            % Plot the graph
            a = x(1);
            b = x(length(x));
            t = a : (b - a)/100 : b;
            y_P = arrayfun(matlabFunction(P), t);
            hold(app.interpolationUIAxes, "off");
            plot(app.interpolationUIAxes, t, y_P);
            hold(app.interpolationUIAxes, "on");
            plot(app.interpolationUIAxes, x, y, '*');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.equationTab = uitab(app.TabGroup);
            app.equationTab.Title = 'Equation';
            app.equationUIAxes = uiaxes(app.equationTab);
            title(app.equationUIAxes, 'Graph')
            xlabel(app.equationUIAxes, 'x')
            ylabel(app.equationUIAxes, 'f(x)')
            zlabel(app.equationUIAxes, 'Z')
            app.PhngtrnhLabel = uilabel(app.equationTab);
            app.PhngtrnhLabel.Text = 'Function';
            app.funcEditField = uieditfield(app.equationTab, 'text');
            app.funcEditField.Placeholder = 'f(x)';
            app.intervalEditField = uieditfield(app.equationTab, 'text');
            app.MethodLabel = uilabel(app.equationTab);
            app.MethodLabel.Text = 'Method';
            app.equation_methodListBox = uilistbox(app.equationTab);
            app.equation_methodListBox.Items = {'Bisection', 'Interation', 'Newton'};
            app.equation_methodListBox.Value = 'Bisection';
            app.intervalLabel = uilabel(app.equationTab);
            app.intervalLabel.Text = 'Interval';
            app.aEquation_EditField = uieditfield(app.equationTab, 'numeric');
            app.aEquation_EditField.Placeholder = 'a';
            app.bEquation_EditField = uieditfield(app.equationTab, 'numeric');
            app.bEquation_EditField.Placeholder = 'b';
            app.RootLabel = uilabel(app.equationTab);
            app.RootLabel.Text = 'Solution';
            app.rootEditField = uieditfield(app.equationTab, 'numeric');
            app.rootEditField.Editable = 'off';
            app.RepeatLabel = uilabel(app.equationTab);
            app.RepeatLabel.Text = 'Repeat';
            app.repeatEditField = uieditfield(app.equationTab, 'numeric');
            app.repeatEditField.Editable = 'off';
            app.equationButton = uibutton(app.equationTab, 'push');
            app.equationButton.ButtonPushedFcn = createCallbackFcn(app, @equationButtonPushed, true);
            app.equationButton.Text = 'Run';
            app.ErrorTextAreaLabel = uilabel(app.equationTab);
            app.ErrorTextAreaLabel.Text = 'Error';
            app.errorTextArea = uitextarea(app.equationTab);
            app.errorTextArea.Editable = 'off';
            app.PhngtrnhLabel_5 = uilabel(app.equationTab);
            app.PhngtrnhLabel_5.Text = 'Error range';
            app.epsilonEditField = uieditfield(app.equationTab, 'text');
            app.epsilonEditField.Placeholder = '0.001';
            app.interpolationTab = uitab(app.TabGroup);
            app.interpolationTab.Title = 'Interpolation';
            app.interpolationUIAxes = uiaxes(app.interpolationTab);
            title(app.interpolationUIAxes, 'Graph')
            xlabel(app.interpolationUIAxes, 'x')
            ylabel(app.interpolationUIAxes, 'f(x)')
            zlabel(app.interpolationUIAxes, 'Z')
            app.interpolationButton = uibutton(app.interpolationTab, 'push');
            app.interpolationButton.ButtonPushedFcn = createCallbackFcn(app, @interpolationButtonPushed, true);
            app.interpolationButton.Text = 'Run';
            app.PhngtrnhLabel_2 = uilabel(app.interpolationTab);
            app.PhngtrnhLabel_2.Text = 'Array x';
            app.interpolation_XField = uieditfield(app.interpolationTab, 'text');
            app.interpolation_XField.Placeholder = '0, 0, 0, ...';
            app.PhngtrnhLabel_3 = uilabel(app.interpolationTab);
            app.PhngtrnhLabel_3.Text = 'Array y';
            app.interpolation_YField = uieditfield(app.interpolationTab, 'text');
            app.interpolation_YField.Placeholder = '0, 0, 0, ...';
            app.MethodLabel_2 = uilabel(app.interpolationTab);
            app.MethodLabel_2.Text = 'Method';
            app.interpolation_methodListBox = uilistbox(app.interpolationTab);
            app.interpolation_methodListBox.Items = {'Lagrange', 'Newton'};
            app.interpolation_methodListBox.Value = 'Lagrange';
            app.interpolationLabel = uilabel(app.interpolationTab);
            app.interpolationLabel.Text = 'Value to interpolate';
            app.interpolation_x0EditField = uieditfield(app.interpolationTab, 'numeric');
            app.interpolation_x0EditField.Placeholder = '0';
            app.interpolation_rootField = uieditfield(app.interpolationTab, 'numeric');
            app.interpolation_rootField.Editable = 'off';
            app.PhngtrnhLabel_4 = uilabel(app.interpolationTab);
            app.PhngtrnhLabel_4.Text = 'Interpolation polynomial';
            app.polynomialArea = uitextarea(app.interpolationTab);
            app.polynomialArea.Editable = 'off';
            app.regressionTab = uitab(app.TabGroup);
            app.regressionTab.Title = 'Regression';
            app.regressionUIAxes = uiaxes(app.regressionTab);
            title(app.regressionUIAxes, 'Đồ thị')
            xlabel(app.regressionUIAxes, 'x')
            ylabel(app.regressionUIAxes, 'f(x)')
            zlabel(app.regressionUIAxes, 'Z')
            app.derivationTab = uitab(app.TabGroup);
            app.derivationTab.Title = 'Derivation';
            app.derivationUIAxes = uiaxes(app.derivationTab);
            title(app.derivationUIAxes, 'Đồ thị')
            xlabel(app.derivationUIAxes, 'x')
            ylabel(app.derivationUIAxes, 'f(x)')
            zlabel(app.derivationUIAxes, 'Z')
            app.integrationTab = uitab(app.TabGroup);
            app.integrationTab.Title = 'Integration';
            app.integrationUIAxes = uiaxes(app.integrationTab);
            title(app.integrationUIAxes, 'Đồ thị')
            xlabel(app.integrationUIAxes, 'x')
            ylabel(app.integrationUIAxes, 'f(x)')
            zlabel(app.integrationUIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Formulas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end